classdef Project_GUI < matlab.apps.AppBase

          % Properties that correspond to app components
          properties (Access = public)
                    UIFigure                       matlab.ui.Figure
                    T_value                        matlab.ui.control.EditField
                    ThresholdKnob                  matlab.ui.control.Knob
                    ThresholdKnobLabel             matlab.ui.control.Label
                    Label_5                        matlab.ui.control.Label
                    RankProperitiesTextArea        matlab.ui.control.TextArea
                    RankProperitiesTextAreaLabel   matlab.ui.control.Label
                    ShapeProperitiesTextArea       matlab.ui.control.TextArea
                    ShapeProperitiesTextAreaLabel  matlab.ui.control.Label
                    DisplayresultButton            matlab.ui.control.Button
                    Card_Value                     matlab.ui.control.EditField
                    CardEditFieldLabel             matlab.ui.control.Label
                    GetcardshapeandproperitiesButton  matlab.ui.control.Button
                    Label_4                        matlab.ui.control.Label
                    Label_3                        matlab.ui.control.Label
                    Label_2                        matlab.ui.control.Label
                    Label                          matlab.ui.control.Label
                    GetcardrankandproperitiesButton  matlab.ui.control.Button
                    BinarizeimageButton            matlab.ui.control.Button
                    LoadcustomimageButton          matlab.ui.control.Button
                    UI_binary_img                  matlab.ui.control.UIAxes
                    UI_card_shape                  matlab.ui.control.UIAxes
                    UI_card_rank                   matlab.ui.control.UIAxes
                    UI_img                         matlab.ui.control.UIAxes
          end

          
          properties (Access = public)
                    % Variables Init
                    T = 90; % Threshold for binarization
                    img 
                    gray
                    thresh
                    rank
                    shape
                    circularity
                    area
                    eccentricity
                    perimeter
                    white_img
          end
          
          methods (Access = public)
            % Binarize picture
            function binarize(app)
               app.gray = rgb2gray(app.img);      
               app.thresh = app.gray < app.T;
               imshow(app.thresh,'Parent',app.UI_binary_img);     
            end
            
            % Get card rank
            function get_card_rank(app)
              app.T_value.Value = num2str(app.T);
              app.ThresholdKnob.Value = app.T;
              card_rank_dim = [5 3 25 35];
              cropped_num = imcrop(app.thresh,card_rank_dim);
              cropped_num = bwareaopen(cropped_num,10);
              app.get_num(cropped_num);
              imshow(cropped_num,'Parent',app.UI_card_rank);       
            end
            
            % Get card shape
            function get_card_shape(app)
              card_shape_dim = [5 40 25 30];
              cropped_shape = imcrop(app.thresh,card_shape_dim);
              app.get_shape(cropped_shape);
              imshow(cropped_shape,'Parent',app.UI_card_shape);       
            end
                    
            function reset(app) % GUI Quality 
            imshow(app.white_img,'Parent',app.UI_img);
            imshow(app.white_img,'Parent',app.UI_binary_img);
            
            cla(app.UI_card_rank,'reset');
            app.UI_card_rank.XTick = [];
            app.UI_card_rank.YTick = [];
            app.UI_card_rank.Box = 'on';
            app.UI_card_rank.Toolbar.Visible = 'off';
            
            cla(app.UI_card_shape,'reset');
            app.UI_card_shape.XTick = [];
            app.UI_card_shape.YTick = [];
            app.UI_card_shape.Box = 'on';
            app.UI_card_shape.Toolbar.Visible = 'off';
  
            app.RankProperitiesTextArea.Value = '';
            app.ShapeProperitiesTextArea.Value = '';
            app.Card_Value.Value = '';
            
            rectangle(app.UI_img,'Position',[0 0 234 333]);
            rectangle(app.UI_binary_img,'Position',[0 0 234 333]);
            
            end
            
            function get_shape(app,ROI)
              colour_img = imcrop(app.img,[5 40 25 30]);
              pixel_value = colour_img(15,13,:);
          
              % Colour between black or red
              if pixel_value(:,:,1) > 200 && pixel_value(:,:,2) == 0 && pixel_value(:,:,3) == 0
                  pixel_value = 'red';
              else
                  pixel_value = 'black';
              end
          
              prop_shape = regionprops(ROI, 'area','Circularity','Eccentricity','Perimeter');
              if length(prop_shape) == 1
                 [Area,Circularity,Eccentricity,Perimeter] = deal(prop_shape.Area,prop_shape.Circularity,prop_shape.Eccentricity,prop_shape.Perimeter);
                 Circularity = round(Circularity,2);
                 Eccentricity = round(Eccentricity,2);
                 Perimeter = round(Perimeter,2);
                 app.ShapeProperitiesTextArea.Value{1} = append('Area: ',num2str(Area));
                 app.ShapeProperitiesTextArea.Value{2} = append('Circularity: ',num2str(Circularity));
                 app.ShapeProperitiesTextArea.Value{3} = append('Eccentricity: ',num2str(Eccentricity));
                 app.ShapeProperitiesTextArea.Value{4} = append('Perimeter: ',num2str(Perimeter));
                 
                 if contains(pixel_value,'red')
                    if (Circularity < 0.9) % Unique feature between heart and diamond
                        app.shape = 'heart';                 
                    else 
                        app.shape = 'diamond';
                    end
                 else 
                    if (Perimeter > 65.5) % Unique feature between spade and club
                        app.shape = 'spade';
                  
                    else 
                      app.shape = 'club';
                    end
                 end
              else
                 imshow(app.thresh(ROI),'Parent',app.UI_card_shape);
                 app.ShapeProperitiesTextArea.Value = '';
                 app.shape = '';
              end    
            end
            
            function get_num(app,ROI)
              prop_num = regionprops(ROI, 'area','Circularity','Eccentricity','Perimeter');
              if length(prop_num) == 2
                  app.rank = '10';
                  app.RankProperitiesTextArea.Value{1} = '10 has 2 numbers';
                  app.RankProperitiesTextArea.Value{2} = '-> Unique Feature';
              elseif length(prop_num) == 1
                        [Area,Circularity,Eccentricity,Perimeter] = deal(prop_num.Area,prop_num.Circularity,prop_num.Eccentricity,prop_num.Perimeter);
                        Circularity = round(Circularity,2);
                        Eccentricity = round(Eccentricity,2);
                        Perimeter = round(Perimeter,2);      
                        app.RankProperitiesTextArea.Value{1} = append('Area: ',num2str(Area));
                        app.RankProperitiesTextArea.Value{2} = append('Circularity: ',num2str(Circularity));
                        app.RankProperitiesTextArea.Value{3} = append('Eccentricity: ',num2str(Eccentricity));
                        app.RankProperitiesTextArea.Value{4} = append('Perimeter: ',num2str(Perimeter));                        
                        
                        if     ( 0.3 < Circularity) && (Circularity < 0.4)   && (250 < Area) && (Area < 300) && (95 < Perimeter) && (Perimeter < 100) && (0.73 < Eccentricity) && (Eccentricity < 0.78)
                            app.rank = '9';
                        elseif ( 0.5 < Circularity) && (Circularity < 0.65)   && (250 < Area) && (Area < 310) && (75 < Perimeter) && (Perimeter < 80) && (0.75 < Eccentricity) && (Eccentricity < 0.8)
                            app.rank = '8';
                        elseif ( 0.25 < Circularity)&& (Circularity < 0.3)  && (175 < Area) && (Area < 215) && (90 < Perimeter) && (Perimeter < 100) && (0.83 < Eccentricity) && (Eccentricity < 0.88)
                            app.rank = '7';
                        elseif ( 0.34 < Circularity) && (Circularity < 0.44)   && (190 < Area) && (Area < 230) && (80 < Perimeter) && (Perimeter < 90) && (0.8 < Eccentricity) && (Eccentricity < 0.9)
                            app.rank = 'ace';
                        elseif ( 0.35 < Circularity) && (Circularity < 0.45)   && (200 < Area) && (Area < 220) && (75 < Perimeter) && (Perimeter < 85) && (0.9 < Eccentricity) && (Eccentricity < 0.95)
                            app.rank = 'jack';
                        elseif ( 0.45 < Circularity) && (Circularity < 0.55)   && (200 < Area) && (Area < 250) && (70 < Perimeter) && (Perimeter < 80) && (0.75 < Eccentricity) && (Eccentricity < 0.8)
                            app.rank = 'queen';    
                        elseif ( 0.2 < Circularity) && (Circularity < 0.3)   && (240 < Area) && (Area < 300) && Perimeter > 110
                            app.rank = 'king';          
                        end
              else
                 imshow(app.thresh(ROI),'Parent',app.UI_card_rank);
                 app.RankProperitiesTextArea.Value = '';
                 app.rank = '';
              end
            end
          end
          

          % Callbacks that handle component events
          methods (Access = private)

                    % Code that executes after component creation
                    function startupFcn(app)
                              % Fit UI_Axes to picture size
                              app.white_img = ones(333,234,3);
                              app.white_img(:,:,3) = 255;
                              imshow(app.white_img,'Parent',app.UI_img);
                              imshow(app.white_img,'Parent',app.UI_binary_img);
                              rectangle(app.UI_img,'Position',[0 0 234 333]);
                              rectangle(app.UI_binary_img,'Position',[0 0 234 333]);
                    end

                    % Button pushed function: LoadcustomimageButton
                    function LoadcustomimageButtonPushed(app, event)
            % Reset after choosing new picture
            app.reset()
            [filename, pathname] = uigetfile('*.*', 'Pick an Image');
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(pathname))
            filename=strcat(pathname,filename);
            app.img=imread(filename);
            imshow(app.img,'Parent',app.UI_img);
            figure(app.UIFigure);     
            else 
            % Update white picture if canceled
            app.reset()
            end
            
                    end

                    % Button pushed function: BinarizeimageButton
                    function BinarizeimageButtonPushed(app, event)
                              app.binarize();
                    end

                    % Button pushed function: 
                    % GetcardrankandproperitiesButton
                    function GetcardrankandproperitiesButtonPushed(app, event)
                              app.get_card_rank();
                    end

                    % Button pushed function: 
                    % GetcardshapeandproperitiesButton
                    function GetcardshapeandproperitiesButtonPushed(app, event)
                              app.get_card_shape();
                    end

                    % Button pushed function: DisplayresultButton
                    function DisplayresultButtonPushed(app, event)
                             str = sprintf('%s_%s',app.shape,app.rank);
                             app.Card_Value.Value = str;
                    end

                    % Value changed function: T_value
                    function T_valueValueChanged(app, event)
                              app.T = str2double(event.Value);
                              app.ThresholdKnob.Value = app.T;
                              app.binarize();
                              app.get_card_rank();
                              app.get_card_shape(); 
                              app.Card_Value.Value = '';
                    end

                    % Value changed function: ThresholdKnob
                    function ThresholdKnobValueChanged(app, event)
                              app.T = round(app.ThresholdKnob.Value);
                              app.T_value.Value = num2str(app.T);
                              app.binarize();
                              app.get_card_rank();
                              app.get_card_shape();     
                              app.Card_Value.Value = '';
                    end
          end

          % Component initialization
          methods (Access = private)

                    % Create UIFigure and components
                    function createComponents(app)
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                              app.UIFigure = uifigure('Visible', 'off');
                              app.UIFigure.Name = 'MATLAB App';
                              app.UI_img = uiaxes(app.UIFigure);
                              app.UI_img.XTick = [];
                              app.UI_img.YTick = [];
                              app.UI_card_rank = uiaxes(app.UIFigure);
                              app.UI_card_rank.XTick = [];
                              app.UI_card_rank.YTick = [];
                              app.UI_card_shape = uiaxes(app.UIFigure);
                              app.UI_card_shape.XTick = [];
                              app.UI_card_shape.YTick = [];
                              app.UI_binary_img = uiaxes(app.UIFigure);
                              app.UI_binary_img.XTick = [];
                              app.UI_binary_img.YTick = [];
                              app.LoadcustomimageButton = uibutton(app.UIFigure, 'push');
                              app.LoadcustomimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadcustomimageButtonPushed, true);
                              app.LoadcustomimageButton.Text = 'Load custom image';
                              app.BinarizeimageButton = uibutton(app.UIFigure, 'push');
                              app.BinarizeimageButton.ButtonPushedFcn = createCallbackFcn(app, @BinarizeimageButtonPushed, true);
                              app.BinarizeimageButton.Text = 'Binarize image';
                              app.GetcardrankandproperitiesButton = uibutton(app.UIFigure, 'push');
                              app.GetcardrankandproperitiesButton.ButtonPushedFcn = createCallbackFcn(app, @GetcardrankandproperitiesButtonPushed, true);
                              app.GetcardrankandproperitiesButton.Text = 'Get card rank and properities';
                              app.Label = uilabel(app.UIFigure);
                              app.Label.Text = '1.';
                              app.Label_2 = uilabel(app.UIFigure);
                              app.Label_2.Text = '2.';
                              app.Label_3 = uilabel(app.UIFigure);
                              app.Label_3.Text = '3.';
                              app.Label_4 = uilabel(app.UIFigure);
                              app.Label_4.Text = '4.';
                              app.GetcardshapeandproperitiesButton = uibutton(app.UIFigure, 'push');
                              app.GetcardshapeandproperitiesButton.ButtonPushedFcn = createCallbackFcn(app, @GetcardshapeandproperitiesButtonPushed, true);
                              app.GetcardshapeandproperitiesButton.Text = 'Get card shape and properities';
                              app.CardEditFieldLabel = uilabel(app.UIFigure);
                              app.CardEditFieldLabel.Text = 'Card ->';
                              app.Card_Value = uieditfield(app.UIFigure, 'text');
                              app.DisplayresultButton = uibutton(app.UIFigure, 'push');
                              app.DisplayresultButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayresultButtonPushed, true);
                              app.DisplayresultButton.Text = 'Display result';
                              app.ShapeProperitiesTextAreaLabel = uilabel(app.UIFigure);
                              app.ShapeProperitiesTextAreaLabel.Text = 'Shape Properities';
                              app.ShapeProperitiesTextArea = uitextarea(app.UIFigure);
                              app.ShapeProperitiesTextArea.Editable = 'off';
                              app.RankProperitiesTextAreaLabel = uilabel(app.UIFigure);
                              app.RankProperitiesTextAreaLabel.Text = 'Rank Properities';
                              app.RankProperitiesTextArea = uitextarea(app.UIFigure);
                              app.RankProperitiesTextArea.Editable = 'off';
                              app.Label_5 = uilabel(app.UIFigure);
                              app.Label_5.Text = '5.';
                              app.ThresholdKnobLabel = uilabel(app.UIFigure);
                              app.ThresholdKnobLabel.Text = 'Threshold';
                              app.ThresholdKnob = uiknob(app.UIFigure, 'continuous');
                              app.ThresholdKnob.Limits = [0 255];
                              app.ThresholdKnob.ValueChangedFcn = createCallbackFcn(app, @ThresholdKnobValueChanged, true);
                              app.T_value = uieditfield(app.UIFigure, 'text');
                              app.T_value.ValueChangedFcn = createCallbackFcn(app, @T_valueValueChanged, true);
                              % Show the figure after all components are created
                              app.UIFigure.Visible = 'on';
                    end
          end

          % App creation and deletion
          methods (Access = public)

                    % Construct app
                    function app = Project_GUI

                              % Create UIFigure and components
                              createComponents(app)

                              % Register the app with App Designer
                              registerApp(app, app.UIFigure)

                              % Execute the startup function
                              runStartupFcn(app, @startupFcn)

                              if nargout == 0
                                        clear app
                              end
                    end

                    % Code that executes before app deletion
                    function delete(app)

                              % Delete UIFigure when app is deleted
                              delete(app.UIFigure)
                    end
          end
end